classdef ESSControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainMenu                        matlab.ui.container.Menu
        LoadsessionMenu                 matlab.ui.container.Menu
        SavesessionMenu                 matlab.ui.container.Menu
        SavesessionandexitMenu          matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        OtherMenu                       matlab.ui.container.Menu
        AboutMenu_2                     matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DATAACQUISITIONTab              matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        UIAxesImp                       matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        ImportExportTab                 matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        ChoosefileButton                matlab.ui.control.Button
        StartdatetimeEditFieldLabel     matlab.ui.control.Label
        StartdatetimeEditField          matlab.ui.control.EditField
        TimeresolutionButtonGroup       matlab.ui.container.ButtonGroup
        SecondsButton                   matlab.ui.control.RadioButton
        MinutesButton                   matlab.ui.control.RadioButton
        HoursButton                     matlab.ui.control.RadioButton
        HoursLinButton                  matlab.ui.control.RadioButton
        ImportstatusLabel               matlab.ui.control.Label
        Format15122019145050Label       matlab.ui.control.Label
        DatePicker_2                    matlab.ui.control.DatePicker
        StatusName                      matlab.ui.control.Label
        DataapplysectionPanel           matlab.ui.container.Panel
        MathfunctionButtonGroup         matlab.ui.container.ButtonGroup
        InsertButton                    matlab.ui.control.RadioButton
        AddtocurrentButton              matlab.ui.control.RadioButton
        MultiplybycurrentButton         matlab.ui.control.RadioButton
        APPLYsetvariableButton          matlab.ui.control.Button
        LoadvariableButton              matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        VariableListBoxLabel            matlab.ui.control.Label
        VariableListBox                 matlab.ui.control.ListBox
        PreprocessingTab                matlab.ui.container.Tab
        MixdataPanel                    matlab.ui.container.Panel
        KeepdaysEditFieldLabel          matlab.ui.control.Label
        KeepdaysEditField               matlab.ui.control.EditField
        MixondaysLabel                  matlab.ui.control.Label
        MixonminutesLabel               matlab.ui.control.Label
        MixdaysButton                   matlab.ui.control.Button
        MixminutesButton                matlab.ui.control.Button
        KeepmonthsEditFieldLabel        matlab.ui.control.Label
        KeepmonthsEditField             matlab.ui.control.EditField
        DatapreprocessingPanel          matlab.ui.container.Panel
        kEditFieldLabel                 matlab.ui.control.Label
        kEditField                      matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AddtokButton                    matlab.ui.control.RadioButton
        MultiplybykButton               matlab.ui.control.RadioButton
        ApplyButton_From                matlab.ui.control.Button
        FromEditField_3Label            matlab.ui.control.Label
        FromEditField_3                 matlab.ui.control.EditField
        ToEditField_3Label              matlab.ui.control.Label
        ToEditField_3                   matlab.ui.control.EditField
        ApplyButton_To                  matlab.ui.control.Button
        ApplyButton_GreaterThan         matlab.ui.control.Button
        ApplyButton_LessThan            matlab.ui.control.Button
        FilterLabel                     matlab.ui.control.Label
        ApplyButton_AddMulti            matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        EditField_GreaterThan           matlab.ui.control.NumericEditField
        EditField_8Label                matlab.ui.control.Label
        EditField_LessThan              matlab.ui.control.NumericEditField
        DatePicker_3                    matlab.ui.control.DatePicker
        DatePicker_4                    matlab.ui.control.DatePicker
        VariationsPanel                 matlab.ui.container.Panel
        SigmayearmonthdayhourminuteTextAreaLabel  matlab.ui.control.Label
        SigmaText                       matlab.ui.control.TextArea
        RandomizeButton                 matlab.ui.control.Button
        ProfilegeneratorTab             matlab.ui.container.Tab
        CreatePanel                     matlab.ui.container.Panel
        Label_6                         matlab.ui.control.Label
        FromDatePickerLabel             matlab.ui.control.Label
        CreateFrom                      matlab.ui.control.DatePicker
        ToDatePickerLabel               matlab.ui.control.Label
        CreateTo                        matlab.ui.control.DatePicker
        CreateSet                       matlab.ui.control.Button
        SetweekendsCheckBox             matlab.ui.control.CheckBox
        DaysTextArea                    matlab.ui.control.TextArea
        WeekendsTextAreaLabel           matlab.ui.control.Label
        WeekendsTextArea                matlab.ui.control.TextArea
        Label_7                         matlab.ui.control.Label
        DaysLabel                       matlab.ui.control.Label
        GenSetValue                     matlab.ui.control.NumericEditField
        GenSetvalueButton               matlab.ui.control.Button
        PlotdesignerTab                 matlab.ui.container.Tab
        DatetimefilterPanel             matlab.ui.container.Panel
        FromEditField_2Label            matlab.ui.control.Label
        FromEditField_2                 matlab.ui.control.EditField
        ToEditField_2Label              matlab.ui.control.Label
        ToEditField_2                   matlab.ui.control.EditField
        FromDatePicker                  matlab.ui.control.DatePicker
        ToDatePicker                    matlab.ui.control.DatePicker
        AllrangeButton_2                matlab.ui.control.Button
        SelectButtonGroup               matlab.ui.container.ButtonGroup
        AllButton                       matlab.ui.control.RadioButton
        WeekdaysButton                  matlab.ui.control.RadioButton
        WeekendsButton                  matlab.ui.control.RadioButton
        PlottypeListBoxLabel            matlab.ui.control.Label
        PlottypeListBox                 matlab.ui.control.ListBox
        AggregateButtonGroup            matlab.ui.container.ButtonGroup
        OriginalButton_2                matlab.ui.control.RadioButton
        MinutesButton_2                 matlab.ui.control.RadioButton
        HoursButton_2                   matlab.ui.control.RadioButton
        DaysButton_2                    matlab.ui.control.RadioButton
        MonthsButton                    matlab.ui.control.RadioButton
        PlotparametersPanel             matlab.ui.container.Panel
        MinCheckBox                     matlab.ui.control.CheckBox
        MaxCheckBox                     matlab.ui.control.CheckBox
        AverageCheckBox                 matlab.ui.control.CheckBox
        MedianCheckBox                  matlab.ui.control.CheckBox
        EditField_Pt1                   matlab.ui.control.EditField
        EditField_Pt2                   matlab.ui.control.EditField
        EditField_Pt3                   matlab.ui.control.EditField
        EditField_Pt4                   matlab.ui.control.EditField
        LinestyleLabel                  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SaveFigButton                   matlab.ui.control.Button
        SaveJPGButton                   matlab.ui.control.Button
        SavedataButton                  matlab.ui.control.Button
        ButtonGrid                      matlab.ui.control.Button
        ButtonGrid_2                    matlab.ui.control.Button
        SHOWButton                      matlab.ui.control.Button
        ProfileResetButton              matlab.ui.control.Button
        RestoreoriginalButton           matlab.ui.control.Button
        COMPONENTSTab                   matlab.ui.container.Tab
        PVSPanel                        matlab.ui.container.Panel
        DefinetheparametersofPVsystemLabel  matlab.ui.control.Label
        InstalledpowerkWpEditFieldLabel  matlab.ui.control.Label
        InstalledpowerkWpEditField      matlab.ui.control.NumericEditField
        InvertereffEditFieldLabel       matlab.ui.control.Label
        InvertereffEditField            matlab.ui.control.NumericEditField
        InverterpowerACmaxEditFieldLabel  matlab.ui.control.Label
        InverterpowerACmaxEditField     matlab.ui.control.NumericEditField
        NoctEditFieldLabel              matlab.ui.control.Label
        NoctEditField                   matlab.ui.control.NumericEditField
        NambEditFieldLabel              matlab.ui.control.Label
        NambEditField                   matlab.ui.control.NumericEditField
        ModulepowertempcoeffEditFieldLabel  matlab.ui.control.Label
        ModulepowertempcoeffEditField   matlab.ui.control.NumericEditField
        ESSPanel                        matlab.ui.container.Panel
        DefinetheparametersofESSbatterystoragesystemLabel  matlab.ui.control.Label
        CappacittykWhEditFieldLabel     matlab.ui.control.Label
        CappacittykWhEditField          matlab.ui.control.NumericEditField
        InitialSOCEditFieldLabel        matlab.ui.control.Label
        InitialSOCEditField             matlab.ui.control.NumericEditField
        CyclingmaxEditFieldLabel        matlab.ui.control.Label
        CyclingmaxEditField             matlab.ui.control.NumericEditField
        ChargeefficiencyEditFieldLabel  matlab.ui.control.Label
        ChargeefficiencyEditField       matlab.ui.control.NumericEditField
        DischargeefficiencyEditFieldLabel  matlab.ui.control.Label
        DischargeefficiencyEditField    matlab.ui.control.NumericEditField
        CyclingminLabel                 matlab.ui.control.Label
        CyclingminEditField             matlab.ui.control.NumericEditField
        PowermaxkWEditFieldLabel        matlab.ui.control.Label
        ESSPowermaxkWEditField          matlab.ui.control.NumericEditField
        GRIDPanel                       matlab.ui.container.Panel
        DefinethegridparametersLabel    matlab.ui.control.Label
        PowerlimitoutkWEditFieldLabel   matlab.ui.control.Label
        PowerlimitoutkWEditField        matlab.ui.control.NumericEditField
        PowerlimitininjectionkWEditFieldLabel  matlab.ui.control.Label
        PowerlimitininjectionkWEditField  matlab.ui.control.NumericEditField
        SIMULATIONTab                   matlab.ui.container.Tab
        InputdatasetsPanel              matlab.ui.container.Panel
        CommontimerangeCheckBox         matlab.ui.control.CheckBox
        StartdatetimeEditField_2Label   matlab.ui.control.Label
        StartdatetimeEditField_2        matlab.ui.control.EditField
        TimestepsEditFieldLabel         matlab.ui.control.Label
        TimestepsEditField              matlab.ui.control.NumericEditField
        DatePicker                      matlab.ui.control.DatePicker
        UIAxesSynch                     matlab.ui.control.UIAxes
        UIAxesPrice                     matlab.ui.control.UIAxes
        UIAxesLoad                      matlab.ui.control.UIAxes
        UIAxesPVS                       matlab.ui.control.UIAxes
        DatareadyLampLabel              matlab.ui.control.Label
        DatareadyLamp                   matlab.ui.control.Lamp
        SHOWPREPAREDATAButton           matlab.ui.control.Button
        SimulationoutputscontrolPanel   matlab.ui.container.Panel
        UIAxesResult2                   matlab.ui.control.UIAxes
        SaveFigButton_2                 matlab.ui.control.Button
        SaveJPGButton_2                 matlab.ui.control.Button
        SavedataButton_2                matlab.ui.control.Button
        UIAxesResult1                   matlab.ui.control.UIAxes
        ControlstrategyButtonGroup      matlab.ui.container.ButtonGroup
        LoadfollowingButton             matlab.ui.control.RadioButton
        PredictivemodelButton           matlab.ui.control.RadioButton
        RUNSIMULATIONButton             matlab.ui.control.Button
        StatTextArea                    matlab.ui.control.TextArea
        StatusLabel                     matlab.ui.control.Label
        AUTOscaleButton                 matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        ShowPVCheckBox                  matlab.ui.control.CheckBox
        ShowGridCheckBox                matlab.ui.control.CheckBox
        ShowESSCheckBox                 matlab.ui.control.CheckBox
        ShowLoadCheckBox                matlab.ui.control.CheckBox
        ClearlogButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        imp1 % object import file
        VarProf1 %object VarProfiles, profile variables as inputs for simulation
        s1  %object Simulation
    end
    
    methods (Access = private)
        
        function PlotResultSim(app)
            plotVar(1)= app.ShowPVCheckBox.Value;
            plotVar(2)= app.ShowGridCheckBox.Value;
            plotVar(3)= app.ShowESSCheckBox.Value;
            plotVar(4)= app.ShowLoadCheckBox.Value;
            
            PlotResult1(app.s1, app.UIAxesResult1, app.s1.timeVar, plotVar )
            
            PlotResult2(app.s1,app.UIAxesResult2,  app.s1.timeVar)
            
        end


    end
    
    methods (Access = public)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc
            
            app.imp1 = ImportF(); %data import obj.
            app.VarProf1 = VarProfiles(); %profile storing obj.
            app.s1 = Sim(); %simulation obj.
            
            %load the init parameters
            app.InstalledpowerkWpEditField.Value = app.VarProf1.Fvs1.par0.Pinst;
            app.InvertereffEditField.Value = app.VarProf1.Fvs1.par0.Eff;
            app.InverterpowerACmaxEditField.Value = app.VarProf1.Fvs1.par0.Plim;
            app.ModulepowertempcoeffEditField.Value = app.VarProf1.Fvs1.par0.Kt;
            app.NoctEditField.Value = app.VarProf1.Fvs1.par0.Noct;
            app.NambEditField.Value = app.VarProf1.Fvs1.par0.Nambt;
            
        end

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            ImportFile(app.imp1, app);
            Show1(app.imp1,app);
        end

        % Button pushed function: ApplyButton_AddMulti
        function ApplyButton_AddMultiPushed(app, event)
            K = app.kEditField.Value;
            if app.AddtokButton.Value ==1 % add to K checked
                AddK(app.imp1, K);
            else
                MultiplyK(app.imp1,K);
            end
            Show1(app.imp1,app);
        end

        % Button pushed function: RestoreoriginalButton
        function RestoreoriginalButtonPushed(app, event)
            RestoreOrig(app.imp1);
            Show1(app.imp1,app);
        end

        % Button pushed function: ApplyButton_From
        function ApplyButton_FromPushed(app, event)
            try
                to1 = datetime ( app.ToEditField.Value, 'InputFormat', 'dd/MM/yyyy HH:mm:ss');
                FilterTo(app.imp1, to1);
            catch
                disp('Wrong format');
                disp(char(app.ToEditField.Value)  );
            end
            Show1(app.imp1,app);
        end

        % Button pushed function: ApplyButton_To
        function ApplyButton_ToPushed(app, event)
            try
                to1 = datetime ( app.ToEditField.Value, 'InputFormat', 'dd/MM/yyyy HH:mm:ss');
                FilterTo(app.imp1, to1);
            catch
                disp('Wrong format');
                disp(char(app.ToEditField.Value)  );
            end
            Show1(app.imp1,app);
        end

        % Button pushed function: ApplyButton_GreaterThan
        function ApplyButton_GreaterThanPushed(app, event)
            
            k=app.EditField_GreaterThan.Value;
            repl=k;
            FilterGreaterThan(app.imp1,k,repl);
            Show1(app.imp1,app);
        end

        % Button pushed function: ApplyButton_LessThan
        function ApplyButton_LessThanPushed(app, event)
            
            k=app.EditField_LessThan.Value;
            repl=k;
            FilterLessThan(app.imp1,k,repl);
            
            Show1(app.imp1,app);
        end

        % Button pushed function: SHOWButton
        function SHOWButtonPushed(app, event)
            Show1(app.imp1,app);
            
        end

        % Button pushed function: ButtonGrid
        function ButtonGridPushed(app, event)
            grid(app.UIAxesImp);
        end

        % Button pushed function: ButtonGrid_2
        function ButtonGrid_2Pushed(app, event)
            xlim(app.UIAxesImp,'auto');
            ylim(app.UIAxesImp,'auto');
        end

        % Value changed function: EditField_Pt1
        function EditField_Pt1ValueChanged(app, event)
            %value = app.EditField_Pt1.Value;
            Show1(app.imp1,app);
        end

        % Value changed function: EditField_Pt2
        function EditField_Pt2ValueChanged(app, event)
            %value = app.EditField_Pt2.Value;
            Show1(app.imp1,app);
        end

        % Value changed function: EditField_Pt3
        function EditField_Pt3ValueChanged(app, event)
            %value = app.EditField_Pt3.Value;
            Show1(app.imp1,app);
        end

        % Value changed function: EditField_Pt4
        function EditField_Pt4ValueChanged(app, event)
            %value = app.EditField_Pt4.Value;
            Show1(app.imp1,app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            ResetData(app.imp1);
            Show1(app.imp1,app);
        end

        % Selection changed function: AggregateButtonGroup
        function AggregateButtonGroupSelectionChanged(app, event)
            %selectedButton = app.AggregateButtonGroup.SelectedObject;
            Show1(app.imp1,app);
        end

        % Selection changed function: SelectButtonGroup
        function SelectButtonGroupSelectionChanged(app, event)
            Show1(app.imp1,app);
        end

        % Value changed function: MinCheckBox
        function MinCheckBoxValueChanged(app, event)
            %value = app.MinCheckBox.Value;
            Show1(app.imp1,app);
        end

        % Value changed function: MaxCheckBox
        function MaxCheckBoxValueChanged(app, event)
            %value = app.MaxCheckBox.Value;
            Show1(app.imp1,app);
        end

        % Value changed function: AverageCheckBox
        function AverageCheckBoxValueChanged(app, event)
            %value = app.AverageCheckBox.Value;
            Show1(app.imp1,app);
        end

        % Value changed function: MedianCheckBox
        function MedianCheckBoxValueChanged(app, event)
            %value = app.MedianCheckBox.Value;
            Show1(app.imp1,app);
        end

        % Button pushed function: APPLYsetvariableButton
        function APPLYsetvariableButtonPushed(app, event)
            SetVar(app.VarProf1,app.imp1, app);
            %variables; import profile; general app
        end

        % Button pushed function: LoadvariableButton
        function LoadvariableButtonPushed(app, event)
            LoadVar(app.VarProf1,app.imp1, app);
            Show1(app.imp1,app);
        end

        % Callback function
        function ButtonPushed(app, event)
            app.InstalledpowerkWpEditField.Value=app.InstalledpowerkWpEditField.Value+1;
        end

        % Value changed function: InvertereffEditField
        function InvertereffEditFieldValueChanged(app, event)
            value = app.InvertereffEditField.Value;
            app.VarProf1.Fvs1.par.Eff = value;
        end

        % Value changed function: InverterpowerACmaxEditField
        function InverterpowerACmaxEditFieldValueChanged(app, event)
            value = app.InverterpowerACmaxEditField.Value;
            app.VarProf1.Fvs1.par.Plim = value;
        end

        % Value changed function: NoctEditField
        function NoctEditFieldValueChanged(app, event)
            value = app.NoctEditField.Value;
            app.VarProf1.Fvs1.par.Kt = value;
        end

        % Value changed function: NambEditField
        function NambEditFieldValueChanged(app, event)
            value = app.NambEditField.Value;
            app.VarProf1.Fvs1.par.Nambt = value;
        end

        % Value changed function: ModulepowertempcoeffEditField
        function ModulepowertempcoeffEditFieldValueChanged(app, event)
            value = app.ModulepowertempcoeffEditField.Value;
            app.VarProf1.Fvs1.par.Pinst = value;
        end

        % Value changed function: CappacittykWhEditField
        function CappacittykWhEditFieldValueChanged(app, event)
            value = app.CappacittykWhEditField.Value;
            app.VarProf1.Ess1.par.Cap = value;
        end

        % Value changed function: ChargeefficiencyEditField
        function ChargeefficiencyEditFieldValueChanged(app, event)
            value = app.ChargeefficiencyEditField.Value;
            app.VarProf1.Ess1.par.EffIn = value;
        end

        % Value changed function: DischargeefficiencyEditField
        function DischargeefficiencyEditFieldValueChanged(app, event)
            value = app.DischargeefficiencyEditField.Value;
            app.VarProf1.Ess1.par.EffOut = value;
        end

        % Callback function
        function EditField2ValueChanged(app, event)
            
        end

        % Button pushed function: ProfileResetButton
        function ProfileResetButtonPushed(app, event)
            ResetData(app.imp1);
            Show1(app.imp1,app);
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            % proceed the Profile randomization
            SigmaText1=app.SigmaText.Value;
            SigmaNum=str2double(SigmaText1);
            try
                randProfile(app.imp1.dataMin, SigmaNum(:) ); %randomizes to years...minutes
                Show1(app.imp1,app);
                
            catch
                warning(['Wrong sigma input', SigmaText1]);
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            UIFigureCloseRequest(app, event)
            
               
        end

        % Button pushed function: CreateSet
        function CreateSetButtonPushed(app, event)
    %set profile
    %app.WeekdaysEditField.Value
    %app.WeekendsEditField.Value
    %app.CreateFrom.Value
    %app.CreateTo.Value
    Prof1 = str2double(app.DaysTextArea.Value);
    
    
    if app.SetweekendsCheckBox.Value == true
        Prof2 = str2double(app.WeekendsTextArea.Value);
        
        setProfile(app.imp1.dataMin,Prof1(:), Prof2(:) , 'from',  app.CreateFrom.Value, 'to', app.CreateTo.Value );
    else
        % setProfile(app.imp1.dataMin,Prof1(:),[0]  , 'plot','from',  app.CreateFrom.Value, 'to', app.CreateTo.Value );
        setProfile(app.imp1.dataMin,Prof1(:),'from', app.CreateFrom.Value, 'to', app.CreateTo.Value );
        Show1(app.imp1,app);
    end
    
    % catch
    %    warning(['Wrong sigma input', SigmaText1]);
    % end


        end

        % Value changed function: SetweekendsCheckBox
        function SetweekendsCheckBoxValueChanged(app, event)
    value = app.SetweekendsCheckBox.Value;
    
    if value ==true % Separately WD, WE
        app.WeekendsTextArea.Enable=true;
        app.DaysLabel.Text= 'Weekdays';
    else % just set all days
        app.WeekendsTextArea.Enable=false;
        app.DaysLabel.Text= 'Days';
    end
        end

        % Button pushed function: GenSetvalueButton
        function GenSetvalueButtonPushed(app, event)
    value = app.GenSetValue.Value;
    app.DaysTextArea.Value=  string(ones(24,1)*value); %set a value for all hours
    if app.SetweekendsCheckBox.Value==true
        app.WeekendsTextArea.Value=  string(ones(24,1)*value); %set a value for all hours in weekend
    end
    
        end

        % Button pushed function: MixdaysButton
        function MixdaysButtonPushed(app, event)
    % mix on days
    daysMix=str2num(app.KeepdaysEditField.Value);
    monthsMix=str2num(app.KeepmonthsEditField.Value);
    
    mixProfile(app.imp1.dataMin, 'days',daysMix(:) ,monthsMix(:));
    Show1(app.imp1,app);
        end

        % Value changed function: InstalledpowerkWpEditField
        function InstalledpowerkWpEditFieldValueChanged(app, event)
    value = app.InstalledpowerkWpEditField.Value;
    
        end

        % Callback function
        function SimSHOWButtonPushed(app, event)
    % preview data for simulation
    SimShow1(app.imp1,app);
    
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
    
    assignin('base','Variable1',app.imp1.dataMin.Profile1); % saves to current workspace
    % saves to file
    try
        [file1,path1 ]= uiputfile([pwd,'\data1.txt'] ,'Choose import data file') ;% choose directory
        pathFile=[path1, file1];
        writetable( timetable2table( app.imp1.dataMin.Profile1 ), pathFile);
        
    catch %ProblemOccured
        warning('Problem saving the data from figure')
        %import status:
        statTxt1= 'Problem saving the data from figure';
        app.ImportstatusLabel.Text= statTxt1;
        
    end


        end

        % Value changed function: SigmaText
        function SigmaTextValueChanged(app, event)
    value = app.SigmaText.Value;
    
        end

        % Value changed function: DatePicker_2
        function DatePicker_2ValueChanged(app, event)
    value = app.DatePicker_2.Value;
    app.StartdatetimeEditField.Value = datestr (value,'dd/mm/yyyy HH:MM:SS' );
    
    
        end

        % Value changed function: DatePicker_3
        function DatePicker_3ValueChanged(app, event)
    value = app.DatePicker_3.Value;
    app.FromEditField_3.Value = datestr (value,'dd/mm/yyyy HH:MM:SS' );
    
        end

        % Value changed function: DatePicker_4
        function DatePicker_4ValueChanged(app, event)
    value = app.DatePicker_4.Value;
    app.ToEditField_3.Value = datestr (value,'dd/mm/yyyy HH:MM:SS' );
    
        end

        % Button pushed function: SaveJPGButton
        function SaveJPGButtonPushed(app, event)
    try
        [file1,path1 ]= uiputfile([pwd,'\Plot1.jpg'] ,'Choose import data file') ;% choose directory
        pathFile=[path1, file1];
        
        %create new extern figure (saveas not directly supported for app UIaxes)
        UIAxes= app.UIAxesImp;
        
        h = figure;
        h.Visible = 'off';
        x = UIAxes.XAxis.Parent.Children.XData;
        y = UIAxes.XAxis.Parent.Children.YData;
        plot(x,y)
        %lgndName1 = UIAxes.Legend.String{1};
        %lgd = legend(lgndName1);
        %lgd.Box = UIAxes.Legend.Box;
        %lgd.Location = UIAxes.Legend.Location;
        h.CurrentAxes.YLabel.String = UIAxes.YLabel.String;
        h.CurrentAxes.YLabel.FontSize = UIAxes.YLabel.FontSize;
        h.CurrentAxes.XLabel.String = UIAxes.XLabel.String;
        h.CurrentAxes.XLabel.FontSize = UIAxes.XLabel.FontSize;
        h.CurrentAxes.Title.String = UIAxes.Title.String;
        h.CurrentAxes.Title.FontSize = UIAxes.Title.FontSize;
        %   h.CurrentAxes.XLim = [0 max(x)];
        %   h.CurrentAxes.XLim = [0 max(y)+1];
        saveas(h,pathFile,'jpg')
        
        %   savefig(h,pathFile)
        delete(h)
        
    catch %ProblemOccured
        warning('Problem saving the figure')
        %import status:
        statTxt1= 'Problem saving the figure';
        app.ImportstatusLabel.Text= statTxt1;
        
    end
    
        end

        % Button pushed function: SaveFigButton
        function SaveFigButtonPushed(app, event)
    try
        [file1,path1 ]= uiputfile([pwd,'\Plot1.fig'] ,'Choose import data file') ;% choose directory
        pathFile=[path1, file1];
        
        %create new extern figure (saveas not directly supported for app UIaxes)
        UIAxes= app.UIAxesImp;
        
        h = figure;
        %  h.Visible = 'off';
        x = UIAxes.XAxis.Parent.Children.XData;
        y = UIAxes.XAxis.Parent.Children.YData;
        plot(x,y)
        %lgndName1 = UIAxes.Legend.String{1};
        %lgd = legend(lgndName1);
        %lgd.Box = UIAxes.Legend.Box;
        %lgd.Location = UIAxes.Legend.Location;
        h.CurrentAxes.YLabel.String = UIAxes.YLabel.String;
        h.CurrentAxes.YLabel.FontSize = UIAxes.YLabel.FontSize;
        h.CurrentAxes.XLabel.String = UIAxes.XLabel.String;
        h.CurrentAxes.XLabel.FontSize = UIAxes.XLabel.FontSize;
        h.CurrentAxes.Title.String = UIAxes.Title.String;
        h.CurrentAxes.Title.FontSize = UIAxes.Title.FontSize;
        %   h.CurrentAxes.XLim = [0 max(x)];
        %   h.CurrentAxes.XLim = [0 max(y)+1];
        %  saveas(h,pathFile,'jpg')
        savefig(h,pathFile)
        delete(h)
        
        
    catch %ProblemOccured
        warning('Problem saving the figure')
        %import status:
        statTxt1= 'Problem saving the figure';
        app.ImportstatusLabel.Text= statTxt1;
        
    end
        end

        % Value changed function: FromDatePicker
        function FromDatePickerValueChanged(app, event)
    value = app.FromDatePicker.Value;
    app.FromEditField_2.Value = datestr (value,'dd/mm/yyyy HH:MM:SS' );
        end

        % Value changed function: ToDatePicker
        function ToDatePickerValueChanged(app, event)
    value = app.ToDatePicker.Value;
    app.ToEditField_2.Value = datestr (value,'dd/mm/yyyy HH:MM:SS' );
        end

        % Callback function
        function AllrangeButtonValueChanged(app, event)
    value = app.AllrangeButton.Value;


        end

        % Button pushed function: AllrangeButton_2
        function AllrangeButton_2Pushed(app, event)
    from1 = app.imp1.dataMin.Profile1.Time(1);
    app.FromEditField_2.Value = datestr (from1,'dd/mm/yyyy HH:MM:SS' );
    
    to1 = app.imp1.dataMin.Profile1.Time(end);
    app.ToEditField_2.Value = datestr (to1,'dd/mm/yyyy HH:MM:SS' );
    Show1(app.imp1,app);
        end

        % Value changed function: FromEditField_2
        function FromEditField_2ValueChanged(app, event)
    value = app.FromEditField_2.Value;
    try %test on format
        datetime (  app.FromEditField_2.Value,  'InputFormat', 'dd/MM/yyyy HH:mm:ss');
    catch
        from1 = app.imp1.dataMin.Profile1.Time(1);
        app.FromEditField_2.Value = datestr (from1,'dd/mm/yyyy HH:MM:SS' );
    end
    
    Show1(app.imp1,app);
        end

        % Value changed function: ToEditField_2
        function ToEditField_2ValueChanged(app, event)
    value = app.ToEditField_2.Value;
    
    try %test on format
        datetime (  app.ToEditField_2.Value,  'InputFormat', 'dd/MM/yyyy HH:mm:ss');
    catch
        to1 = app.imp1.dataMin.Profile1.Time(end);
        app.ToEditField_2.Value = datestr (to1,'dd/mm/yyyy HH:MM:SS' );
    end
    
    Show1(app.imp1,app);
        end

        % Button pushed function: MixminutesButton
        function MixminutesButtonPushed(app, event)
    
    mixProfile(app.imp1.dataMin, 'minutes'); %mix on minutes, keeping hours
    Show1(app.imp1,app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
    
    answer = questdlg('Would you like to exit wihout saving?', 'Exit',...
       	'Yes', 'No', 'No');
    switch answer
        case 'Yes'
            delete(app);
        case 'No'
            
    end
        end

        % Menu selected function: LoadsessionMenu
        function LoadsessionMenuSelected(app, event)
    % loading  session
    
    try 
        
      [file1,path1 ]= uigetfile(['pwd', '\*.mat' ],'Choose data file to load session') ;% choose file
      pathFile=[path1, file1];
              load (pathFile, 'recentSess')
        app.VarProf1 = recentSess.VarProf1;
        app.imp1 = recentSess.imp1;
        %import status:
        statTxt1= 'Recent session loaded';
        app.ImportstatusLabel.Text= statTxt1;
         uialert(app.UIFigure,statTxt1,'Info','Icon','info' );
         
    catch
        %import status:
        statTxt1= 'Error loading recent session';
        app.ImportstatusLabel.Text= statTxt1;
        uialert(app.UIFigure,statTxt1,'Warning','Icon','warning' );
        
    end
    
    Show1(app.imp1,app);


        end

        % Button pushed function: SHOWPREPAREDATAButton
        function SHOWPREPAREDATAButtonPushed(app, event)
    
    % Fvs1, Fvs1Pred plot
    cla(app.UIAxesPVS)
    hold (app.UIAxesPVS, 'on')
    if ~isempty(app.VarProf1.Fvs1.PoutSet.Profile1)
        plot(app.UIAxesPVS, app.VarProf1.Fvs1.PoutSet.Profile1.Time, app.VarProf1.Fvs1.PoutSet.Profile1{:,1},'b'  );
    end
    
    if ~isempty(app.VarProf1.Fvs1Pred.PoutSet.Profile1)
        plot(app.UIAxesPVS, app.VarProf1.Fvs1Pred.PoutSet.Profile1.Time, app.VarProf1.Fvs1Pred.PoutSet.Profile1{:,1},'r'  );
    end
    
    % Load1, Load1Pred plot
    cla(app.UIAxesLoad)
    hold (app.UIAxesLoad, 'on')
    if ~isempty(app.VarProf1.Load1.Profile1)
        plot(app.UIAxesLoad, app.VarProf1.Load1.Profile1.Time, app.VarProf1.Load1.Profile1{:,1},'b'  );
    end
    
    if ~isempty(app.VarProf1.Load1Pred.Profile1)
        plot(app.UIAxesLoad, app.VarProf1.Load1Pred.Profile1.Time, app.VarProf1.Load1Pred.Profile1{:,1},'r'  );
    end
    
    % Grid1 plot
    cla(app.UIAxesPrice)
    hold (app.UIAxesPrice, 'on')
    if ~isempty(app.VarProf1.Grid1.PriceOut.Profile1)
        plot(app.UIAxesPrice, app.VarProf1.Grid1.PriceOut.Profile1.Time, app.VarProf1.Grid1.PriceOut.Profile1{:,1},'b'  );
    end
    
    %Synch plot
    cla(app.UIAxesSynch)
    hold (app.UIAxesSynch, 'on')
    
    
    MinT= NaT;
    MaxT=NaT;
    
    if ~isempty(app.VarProf1.Fvs1Pred.PoutSet.Profile1)
        xFvs1= app.VarProf1.Fvs1Pred.PoutSet.Profile1.Time;
        yFvs1= ~isnan(app.VarProf1.Fvs1Pred.PoutSet.Profile1{:,1})*3;
        plot(app.UIAxesSynch, xFvs1, yFvs1  ,'.r'  );
        
        MinT = min([MinT; app.VarProf1.Fvs1Pred.PoutSet.Profile1.Time] );
        MaxT = max([MaxT; app.VarProf1.Fvs1Pred.PoutSet.Profile1.Time ]);
        
    end
    
    if ~isempty(app.VarProf1.Load1Pred.Profile1)
        xFvs1= app.VarProf1.Load1Pred.Profile1.Time;
        yFvs1= ~isnan( app.VarProf1.Load1Pred.Profile1{:,1})*2;
        plot(app.UIAxesSynch, xFvs1, yFvs1  ,'.r'  );
        
        MinT = min([MinT; app.VarProf1.Load1Pred.Profile1.Time] );
        MaxT = max([MaxT; app.VarProf1.Load1Pred.Profile1.Time ]);
        
    end
    
    if ~isempty(app.VarProf1.Grid1.PriceOut.Profile1)
        xFvs1= app.VarProf1.Grid1.PriceOut.Profile1.Time;
        yFvs1= ~isnan( app.VarProf1.Grid1.PriceOut.Profile1{:,1})*1;
        plot(app.UIAxesSynch, xFvs1, yFvs1  ,'.r'  );
        
        MinT = min([MinT; app.VarProf1.Grid1.PriceOut.Profile1.Time] );
        MaxT = max([MaxT; app.VarProf1.Grid1.PriceOut.Profile1.Time ]);
        
    end
    ylim(app.UIAxesSynch,[0 4]);
    
    % check button 'Common time range'
    if app.CommontimerangeCheckBox.Value==true
        if ~isnat (MinT) & ~isnat(MaxT) & MinT<MaxT
            xlim(app.UIAxesPVS,[MinT , MaxT]);
            xlim(app.UIAxesLoad,[MinT , MaxT]);
            xlim(app.UIAxesPrice,[MinT , MaxT]);
        end
        
    else
        xlim(app.UIAxesPVS,'auto');
        xlim(app.UIAxesLoad, 'auto');
        xlim(app.UIAxesPrice,'auto');
    end
    
    % Prepare data: SimDataTable = (PVoutput, Load, GridPrice);
    SimDataTable= synchronize (app.VarProf1.Fvs1Pred.PoutSet.Profile1,  ...
        app.VarProf1.Load1Pred.Profile1, ...
        app.VarProf1.Grid1.PriceOut.Profile1);
    
    startTime = datetime ( app.StartdatetimeEditField_2.Value, 'InputFormat', 'dd/MM/yyyy HH:mm:ss');
    SimDataTable(SimDataTable.Time< startTime   ,:)=[];
    
    rowsTable=min(app.TimestepsEditField.Value , height(SimDataTable) );
    SimDataTable=SimDataTable(1:rowsTable  ,:); %filtered from : minuteSteps
    
    %plot start & end line of selected timerange
    app.DatareadyLamp.Color='red';
    app.RUNSIMULATIONButton.Enable=false;
    
    if rowsTable>=2
        plot(app.UIAxesSynch, [SimDataTable.Time(1),SimDataTable.Time(1)],[0,4],':k');
        plot(app.UIAxesSynch, [SimDataTable.Time(end),SimDataTable.Time(end)],[0,4],':k');
        
        if width(SimDataTable)==3 && ~ any( isnan(SimDataTable{:,:}), 'all' )
            app.DatareadyLamp.Color='green' ; %no NaN values
            app.RUNSIMULATIONButton.Enable=true; %enable Simulation button
            
            %prepare data to simulation obj. (s1)
            app.s1.PVpred = SimDataTable{:,1};  %PV pred.
            app.s1.LoadPred = SimDataTable{:,2}; %load pred.
            app.s1.gridPrice = SimDataTable{:,3}; %grid price
            app.s1.timeVar=SimDataTable.Time; %time vector data
            
            
            %check on time horizon length
            app.TimestepsEditField.Value=min(app.TimestepsEditField.Value, height(SimDataTable) )                   ;
            
            app.s1.sysPar.sim.Hor= app.TimestepsEditField.Value; %simulation horizon
            app.s1.sysPar.PowerGrid.Lim= app.PowerlimitoutkWEditField.Value; %power grid limit
            
            app.s1.sysPar.ess.Cap= app.CappacittykWhEditField.Value; %ess capacity
            app.s1.sysPar.ess.Min= app.CyclingminEditField.Value * app.s1.sysPar.ess.Cap/100; %ess capacity minimal
            app.s1.sysPar.ess.Max= app.CyclingmaxEditField.Value * app.s1.sysPar.ess.Cap/100; %ess capacity maximal
            app.s1.sysPar.ess.Init= app.InitialSOCEditField.Value* app.s1.sysPar.ess.Cap/100; %ess capacity initial
            app.s1.sysPar.ess.Power= app.ESSPowermaxkWEditField.Value; %ess maximal power
            
            
        end
        
        
    end


        end

        % Menu selected function: AboutMenu_2
        function AboutMenu_2Selected(app, event)
    % popup info window
    infoMsg= { 'Software tool to run a battery control algorithm' , ...
        'based on predictive control and intraday tariffs'...
        ''...
        'Ver.: 0.1 beta'...
        'Developed CTU UCEEB, 2019'...
        'Supported by OPPIK CZ.01.1.02/0.0/15_019/0004906'};
    msgbox(infoMsg)
    
        end

        % Value changed function: StartdatetimeEditField
        function StartdatetimeEditFieldValueChanged(app, event)
    
    try %test on format
        datetime ( app.StartdatetimeEditField.Value,  'InputFormat', 'dd/MM/yyyy HH:mm:ss');
    catch % wrong input datetime format
        app.StartdatetimeEditField.Value=  datestr (datetime('now'),'dd/mm/yyyy HH:MM:SS' );
        warndlg('Wrong input datetime format')
    end
        end

        % Value changed function: FromEditField_3
        function FromEditField_3ValueChanged(app, event)
    
    try %test on format
        datetime ( app.FromEditField_3.Value,  'InputFormat', 'dd/MM/yyyy HH:mm:ss');
    catch % wrong input datetime format
        app.FromEditField_3.Value=  datestr (datetime('now'),'dd/mm/yyyy HH:MM:SS' );
        warndlg('Wrong input datetime format')
    end
        end

        % Value changed function: ToEditField_3
        function ToEditField_3ValueChanged(app, event)
    
    try %test on format
        datetime ( app.ToEditField_3.Value,  'InputFormat', 'dd/MM/yyyy HH:mm:ss');
    catch % wrong input datetime format
        app.ToEditField_3.Value=  datestr (datetime('now'),'dd/mm/yyyy HH:MM:SS' );
        warndlg('Wrong input datetime format')
    end
        end

        % Value changed function: StartdatetimeEditField_2
        function StartdatetimeEditField_2ValueChanged(app, event)
    try %test on format
        datetime ( app.StartdatetimeEditField_2.Value,  'InputFormat', 'dd/MM/yyyy HH:mm:ss');
    catch % wrong input datetime format
        app.StartdatetimeEditField_2.Value=  datestr (datetime('now'),'dd/mm/yyyy HH:MM:SS' );
        warndlg('Wrong input datetime format')
    end
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
    value = app.DatePicker.Value;
    app.StartdatetimeEditField_2.Value = datestr (value,'dd/mm/yyyy HH:MM:SS' );
        end

        % Button pushed function: RUNSIMULATIONButton
        function RUNSIMULATIONButtonPushed(app, event)
    SHOWPREPAREDATAButtonPushed(app)  %update preview, prepare data
    app.StatTextArea.Value=[app.StatTextArea.Value; '--------------------------'  ];
    app.StatTextArea.Value=[app.StatTextArea.Value; 'Simulation started...'  ];
    res1=Simulate1(app.s1);
    app.StatTextArea.Value=[app.StatTextArea.Value; 'Simulation finished...'  ];
    app.StatTextArea.Value=[app.StatTextArea.Value; res1.status  ];
    
    PlotResultSim(app);
    
    %{
            plotVar(1)= app.ShowPVCheckBox.Value;
            plotVar(2)= app.ShowGridCheckBox.Value;
            plotVar(3)= app.ShowESSCheckBox.Value;
            plotVar(4)= app.ShowLoadCheckBox.Value;
            
            PlotResult1(app.s1, app.UIAxesResult1, app.s1.timeVar, plotVar )
            
            PlotResult2(app.s1,app.UIAxesResult2,  app.s1.timeVar)
    %}
    
        end

        % Button pushed function: AUTOscaleButton
        function AUTOscaleButtonPushed(app, event)
    xlim(app.UIAxesResult1, 'auto');
    xlim(app.UIAxesResult2, 'auto');
    ylim(app.UIAxesResult1, 'auto');
    ylim(app.UIAxesResult2, 'auto');
        end

        % Value changed function: ShowPVCheckBox
        function ShowPVCheckBoxValueChanged(app, event)
    value = app.ShowPVCheckBox.Value;
    PlotResultSim(app);
        end

        % Value changed function: ShowGridCheckBox
        function ShowGridCheckBoxValueChanged(app, event)
    value = app.ShowGridCheckBox.Value;
    PlotResultSim(app);
        end

        % Value changed function: ShowESSCheckBox
        function ShowESSCheckBoxValueChanged(app, event)
    value = app.ShowESSCheckBox.Value;
    PlotResultSim(app);
        end

        % Value changed function: ShowLoadCheckBox
        function ShowLoadCheckBoxValueChanged(app, event)
    value = app.ShowLoadCheckBox.Value;
    PlotResultSim(app);
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
    app.StatTextArea.Value='';
        end

        % Menu selected function: SavesessionandexitMenu
        function SavesessionandexitMenuSelected(app, event)
    
    
    answer = questdlg('Save session and exit?', ...
       	'Save and exit','Yes', 'Abort', 'Yes');
    
    switch answer
        
        case 'Yes'
            
            
            try
                [file1,path1 ]= uiputfile(['pwd', '\*.mat' ],'Choose data file to save session') ;% choose file
                pathFile=[path1, file1];
                recentSess.VarProf1 = app.VarProf1;
                recentSess.imp1 = app.imp1;
                save (pathFile, 'recentSess')

                statTxt1= 'Session saved successfully.';
                app.ImportstatusLabel.Text= statTxt1;
                uialert(app.UIFigure,statTxt1,'Info','Icon','info','CloseFcn', @(src,event)delete(app) );
                
                
            catch %ProblemOccured
                statTxt1= 'Problem saving the session';
                app.ImportstatusLabel.Text= statTxt1;
                uialert(app.UIFigure,statTxt1,'Warning','Icon','warning');
                
                
            end
            
        case 'Abort'
            statTxt1= 'Saving session aborted';
               app.ImportstatusLabel.Text= statTxt1;      
    end
    
        end

        % Menu selected function: SavesessionMenu
        function SavesessionMenuSelected(app, event)
              
            try
                [file1,path1 ]= uiputfile(['pwd', '\*.mat' ],'Choose data file to save session') ;% choose file
                pathFile=[path1, file1];
                recentSess.VarProf1 = app.VarProf1;
                recentSess.imp1 = app.imp1;
                save (pathFile, 'recentSess')

                statTxt1= 'Session saved successfully.';
                app.ImportstatusLabel.Text= statTxt1;
                uialert(app.UIFigure,statTxt1,'Info','Icon','info');         
                
            catch %ProblemOccured
                statTxt1= 'Problem saving the session';
                app.ImportstatusLabel.Text= statTxt1;
                uialert(app.UIFigure,statTxt1,'Warning','Icon','warning');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainMenu = uimenu(app.UIFigure);
            app.MainMenu.Text = 'Main';
            app.LoadsessionMenu = uimenu(app.MainMenu);
            app.LoadsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsessionMenuSelected, true);
            app.LoadsessionMenu.Text = 'Load session';
            app.SavesessionMenu = uimenu(app.MainMenu);
            app.SavesessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesessionMenuSelected, true);
            app.SavesessionMenu.Text = 'Save session';
            app.SavesessionandexitMenu = uimenu(app.MainMenu);
            app.SavesessionandexitMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesessionandexitMenuSelected, true);
            app.SavesessionandexitMenu.Text = 'Save session and exit';
            app.ExitMenu = uimenu(app.MainMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.OtherMenu = uimenu(app.UIFigure);
            app.OtherMenu.Text = 'Other';
            app.AboutMenu_2 = uimenu(app.OtherMenu);
            app.AboutMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AboutMenu_2Selected, true);
            app.AboutMenu_2.Text = 'About';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DATAACQUISITIONTab = uitab(app.TabGroup);
            app.DATAACQUISITIONTab.Title = 'DATA ACQUISITION';
            app.Label = uilabel(app.DATAACQUISITIONTab);
            app.Label.Text = '';
            app.UIAxesImp = uiaxes(app.DATAACQUISITIONTab);
            xlabel(app.UIAxesImp, 'Time')
            ylabel(app.UIAxesImp, 'Value')
            app.TabGroup2 = uitabgroup(app.DATAACQUISITIONTab);
            app.ImportExportTab = uitab(app.TabGroup2);
            app.ImportExportTab.Title = 'Import/Export';
            app.Panel_4 = uipanel(app.ImportExportTab);
            app.Panel_4.Title = '+';
            app.ChoosefileButton = uibutton(app.Panel_4, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.StartdatetimeEditFieldLabel = uilabel(app.Panel_4);
            app.StartdatetimeEditFieldLabel.Text = 'Start datetime';
            app.StartdatetimeEditField = uieditfield(app.Panel_4, 'text');
            app.StartdatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartdatetimeEditFieldValueChanged, true);
            app.StartdatetimeEditField.Value = '15/12/2019 14:50:50';
            app.TimeresolutionButtonGroup = uibuttongroup(app.Panel_4);
            app.TimeresolutionButtonGroup.Title = 'Time resolution';
            app.SecondsButton = uiradiobutton(app.TimeresolutionButtonGroup);
            app.SecondsButton.Text = 'Seconds';
            app.SecondsButton.Value = true;
            app.MinutesButton = uiradiobutton(app.TimeresolutionButtonGroup);
            app.MinutesButton.Text = 'Minutes';
            app.HoursButton = uiradiobutton(app.TimeresolutionButtonGroup);
            app.HoursButton.Text = 'Hours';
            app.HoursLinButton = uiradiobutton(app.TimeresolutionButtonGroup);
            app.HoursLinButton.Text = 'Hours linearFit';
            app.ImportstatusLabel = uilabel(app.Panel_4);
            app.ImportstatusLabel.Text = ' -';
            app.Format15122019145050Label = uilabel(app.Panel_4);
            app.Format15122019145050Label.Text = 'Format: 15/12/2019 14:50:50';
            app.DatePicker_2 = uidatepicker(app.Panel_4);
            app.DatePicker_2.ValueChangedFcn = createCallbackFcn(app, @DatePicker_2ValueChanged, true);
            app.DatePicker_2.Value = datetime([2019 5 1]);
            app.StatusName = uilabel(app.Panel_4);
            app.StatusName.Text = 'Status:';
            app.DataapplysectionPanel = uipanel(app.ImportExportTab);
            app.DataapplysectionPanel.Title = 'Data apply section';
            app.MathfunctionButtonGroup = uibuttongroup(app.DataapplysectionPanel);
            app.MathfunctionButtonGroup.Title = 'Math function';
            app.InsertButton = uiradiobutton(app.MathfunctionButtonGroup);
            app.InsertButton.Enable = 'off';
            app.InsertButton.Text = 'Insert';
            app.InsertButton.Value = true;
            app.AddtocurrentButton = uiradiobutton(app.MathfunctionButtonGroup);
            app.AddtocurrentButton.Enable = 'off';
            app.AddtocurrentButton.Text = 'Add to current';
            app.MultiplybycurrentButton = uiradiobutton(app.MathfunctionButtonGroup);
            app.MultiplybycurrentButton.Enable = 'off';
            app.MultiplybycurrentButton.Text = 'Multiply by current';
            app.APPLYsetvariableButton = uibutton(app.DataapplysectionPanel, 'push');
            app.APPLYsetvariableButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYsetvariableButtonPushed, true);
            app.APPLYsetvariableButton.Text = 'APPLY (set variable)';
            app.LoadvariableButton = uibutton(app.DataapplysectionPanel, 'push');
            app.LoadvariableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvariableButtonPushed, true);
            app.LoadvariableButton.Text = 'Load variable';
            app.ResetButton = uibutton(app.DataapplysectionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.VariableListBoxLabel = uilabel(app.DataapplysectionPanel);
            app.VariableListBoxLabel.Text = 'Variable';
            app.VariableListBox = uilistbox(app.DataapplysectionPanel);
            app.VariableListBox.Items = {'PVS AC output Predicted', 'Grid price', 'Load Predicted'};
            app.VariableListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.VariableListBox.Value = '1';
            app.PreprocessingTab = uitab(app.TabGroup2);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.MixdataPanel = uipanel(app.PreprocessingTab);
            app.MixdataPanel.Title = 'Mix data';
            app.KeepdaysEditFieldLabel = uilabel(app.MixdataPanel);
            app.KeepdaysEditFieldLabel.Text = 'Keep days';
            app.KeepdaysEditField = uieditfield(app.MixdataPanel, 'text');
            app.KeepdaysEditField.Value = '2,1,1,1,1,1,2';
            app.MixondaysLabel = uilabel(app.MixdataPanel);
            app.MixondaysLabel.Text = 'Mix on days';
            app.MixonminutesLabel = uilabel(app.MixdataPanel);
            app.MixonminutesLabel.Text = 'Mix on minutes';
            app.MixdaysButton = uibutton(app.MixdataPanel, 'push');
            app.MixdaysButton.ButtonPushedFcn = createCallbackFcn(app, @MixdaysButtonPushed, true);
            app.MixdaysButton.Text = 'Mix days';
            app.MixminutesButton = uibutton(app.MixdataPanel, 'push');
            app.MixminutesButton.ButtonPushedFcn = createCallbackFcn(app, @MixminutesButtonPushed, true);
            app.MixminutesButton.Text = 'Mix minutes';
            app.KeepmonthsEditFieldLabel = uilabel(app.MixdataPanel);
            app.KeepmonthsEditFieldLabel.Text = 'Keep months';
            app.KeepmonthsEditField = uieditfield(app.MixdataPanel, 'text');
            app.KeepmonthsEditField.Value = '[1:12]';
            app.DatapreprocessingPanel = uipanel(app.PreprocessingTab);
            app.DatapreprocessingPanel.Title = 'Data preprocessing';
            app.kEditFieldLabel = uilabel(app.DatapreprocessingPanel);
            app.kEditFieldLabel.Text = 'k =';
            app.kEditField = uieditfield(app.DatapreprocessingPanel, 'numeric');
            app.ButtonGroup = uibuttongroup(app.DatapreprocessingPanel);
            app.AddtokButton = uiradiobutton(app.ButtonGroup);
            app.AddtokButton.Text = 'Add to ''k''';
            app.AddtokButton.Value = true;
            app.MultiplybykButton = uiradiobutton(app.ButtonGroup);
            app.MultiplybykButton.Text = 'Multiply by ''k''';
            app.ApplyButton_From = uibutton(app.DatapreprocessingPanel, 'push');
            app.ApplyButton_From.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_FromPushed, true);
            app.ApplyButton_From.Text = 'Apply';
            app.FromEditField_3Label = uilabel(app.DatapreprocessingPanel);
            app.FromEditField_3Label.Text = 'From';
            app.FromEditField_3 = uieditfield(app.DatapreprocessingPanel, 'text');
            app.FromEditField_3.ValueChangedFcn = createCallbackFcn(app, @FromEditField_3ValueChanged, true);
            app.ToEditField_3Label = uilabel(app.DatapreprocessingPanel);
            app.ToEditField_3Label.Text = 'To';
            app.ToEditField_3 = uieditfield(app.DatapreprocessingPanel, 'text');
            app.ToEditField_3.ValueChangedFcn = createCallbackFcn(app, @ToEditField_3ValueChanged, true);
            app.ApplyButton_To = uibutton(app.DatapreprocessingPanel, 'push');
            app.ApplyButton_To.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_ToPushed, true);
            app.ApplyButton_To.Text = 'Apply';
            app.ApplyButton_GreaterThan = uibutton(app.DatapreprocessingPanel, 'push');
            app.ApplyButton_GreaterThan.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_GreaterThanPushed, true);
            app.ApplyButton_GreaterThan.Text = 'Apply';
            app.ApplyButton_LessThan = uibutton(app.DatapreprocessingPanel, 'push');
            app.ApplyButton_LessThan.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_LessThanPushed, true);
            app.ApplyButton_LessThan.Text = 'Apply';
            app.FilterLabel = uilabel(app.DatapreprocessingPanel);
            app.FilterLabel.Text = 'Filter';
            app.ApplyButton_AddMulti = uibutton(app.DatapreprocessingPanel, 'push');
            app.ApplyButton_AddMulti.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_AddMultiPushed, true);
            app.ApplyButton_AddMulti.Text = 'Apply';
            app.Label_8 = uilabel(app.DatapreprocessingPanel);
            app.Label_8.Text = '>=';
            app.EditField_GreaterThan = uieditfield(app.DatapreprocessingPanel, 'numeric');
            app.EditField_8Label = uilabel(app.DatapreprocessingPanel);
            app.EditField_8Label.Text = '<=';
            app.EditField_LessThan = uieditfield(app.DatapreprocessingPanel, 'numeric');
            app.DatePicker_3 = uidatepicker(app.DatapreprocessingPanel);
            app.DatePicker_3.ValueChangedFcn = createCallbackFcn(app, @DatePicker_3ValueChanged, true);
            app.DatePicker_4 = uidatepicker(app.DatapreprocessingPanel);
            app.DatePicker_4.ValueChangedFcn = createCallbackFcn(app, @DatePicker_4ValueChanged, true);
            app.VariationsPanel = uipanel(app.PreprocessingTab);
            app.VariationsPanel.Title = 'Variations';
            app.SigmayearmonthdayhourminuteTextAreaLabel = uilabel(app.VariationsPanel);
            app.SigmayearmonthdayhourminuteTextAreaLabel.Text = {'Sigma [year]'; '[month]'; '[day]'; '[hour]'; '[minute]'};
            app.SigmaText = uitextarea(app.VariationsPanel);
            app.SigmaText.ValueChangedFcn = createCallbackFcn(app, @SigmaTextValueChanged, true);
            app.SigmaText.Value = {'0'; '0'; '0'; '30'; '0'};
            app.RandomizeButton = uibutton(app.VariationsPanel, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.ProfilegeneratorTab = uitab(app.TabGroup2);
            app.ProfilegeneratorTab.Title = 'Profile generator';
            app.CreatePanel = uipanel(app.ProfilegeneratorTab);
            app.CreatePanel.Title = 'Create';
            app.Label_6 = uilabel(app.CreatePanel);
            app.Label_6.Text = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'};
            app.FromDatePickerLabel = uilabel(app.CreatePanel);
            app.FromDatePickerLabel.Text = 'From';
            app.CreateFrom = uidatepicker(app.CreatePanel);
            app.CreateFrom.Value = datetime([2019 5 1]);
            app.ToDatePickerLabel = uilabel(app.CreatePanel);
            app.ToDatePickerLabel.Text = 'To';
            app.CreateTo = uidatepicker(app.CreatePanel);
            app.CreateTo.Value = datetime([2019 5 3]);
            app.CreateSet = uibutton(app.CreatePanel, 'push');
            app.CreateSet.ButtonPushedFcn = createCallbackFcn(app, @CreateSetButtonPushed, true);
            app.CreateSet.Text = 'Set';
            app.SetweekendsCheckBox = uicheckbox(app.CreatePanel);
            app.SetweekendsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetweekendsCheckBoxValueChanged, true);
            app.SetweekendsCheckBox.Text = 'Set weekends';
            app.DaysTextArea = uitextarea(app.CreatePanel);
            app.DaysTextArea.Value = {'0'; '0'; '0'; '10'; '0'; '0'; '0'; '20'; '30'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'};
            app.WeekendsTextAreaLabel = uilabel(app.CreatePanel);
            app.WeekendsTextAreaLabel.Enable = 'off';
            app.WeekendsTextAreaLabel.Text = 'Weekends';
            app.WeekendsTextArea = uitextarea(app.CreatePanel);
            app.WeekendsTextArea.Enable = 'off';
            app.WeekendsTextArea.Value = {'0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'; '0'};
            app.Label_7 = uilabel(app.CreatePanel);
            app.Label_7.Text = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'};
            app.DaysLabel = uilabel(app.CreatePanel);
            app.DaysLabel.Text = 'Days';
            app.GenSetValue = uieditfield(app.CreatePanel, 'numeric');
            app.GenSetvalueButton = uibutton(app.CreatePanel, 'push');
            app.GenSetvalueButton.ButtonPushedFcn = createCallbackFcn(app, @GenSetvalueButtonPushed, true);
            app.GenSetvalueButton.Text = 'Set value';
            app.PlotdesignerTab = uitab(app.TabGroup2);
            app.PlotdesignerTab.Title = 'Plot designer';
            app.DatetimefilterPanel = uipanel(app.PlotdesignerTab);
            app.DatetimefilterPanel.Title = 'Datetime filter';
            app.FromEditField_2Label = uilabel(app.DatetimefilterPanel);
            app.FromEditField_2Label.Text = 'From';
            app.FromEditField_2 = uieditfield(app.DatetimefilterPanel, 'text');
            app.FromEditField_2.ValueChangedFcn = createCallbackFcn(app, @FromEditField_2ValueChanged, true);
            app.ToEditField_2Label = uilabel(app.DatetimefilterPanel);
            app.ToEditField_2Label.Text = 'To';
            app.ToEditField_2 = uieditfield(app.DatetimefilterPanel, 'text');
            app.ToEditField_2.ValueChangedFcn = createCallbackFcn(app, @ToEditField_2ValueChanged, true);
            app.FromDatePicker = uidatepicker(app.DatetimefilterPanel);
            app.FromDatePicker.ValueChangedFcn = createCallbackFcn(app, @FromDatePickerValueChanged, true);
            app.ToDatePicker = uidatepicker(app.DatetimefilterPanel);
            app.ToDatePicker.ValueChangedFcn = createCallbackFcn(app, @ToDatePickerValueChanged, true);
            app.AllrangeButton_2 = uibutton(app.DatetimefilterPanel, 'push');
            app.AllrangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AllrangeButton_2Pushed, true);
            app.AllrangeButton_2.Text = 'All range';
            app.SelectButtonGroup = uibuttongroup(app.PlotdesignerTab);
            app.SelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectButtonGroupSelectionChanged, true);
            app.SelectButtonGroup.Title = 'Select';
            app.AllButton = uiradiobutton(app.SelectButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.WeekdaysButton = uiradiobutton(app.SelectButtonGroup);
            app.WeekdaysButton.Text = 'Weekdays';
            app.WeekendsButton = uiradiobutton(app.SelectButtonGroup);
            app.WeekendsButton.Text = 'Weekends';
            app.PlottypeListBoxLabel = uilabel(app.PlotdesignerTab);
            app.PlottypeListBoxLabel.Enable = 'off';
            app.PlottypeListBoxLabel.Text = 'Plot type';
            app.PlottypeListBox = uilistbox(app.PlotdesignerTab);
            app.PlottypeListBox.Items = {'Variable standard', 'Variable daily mean', 'PVS AC out x Load', 'PVS AC out Pred x Load Pred', ''};
            app.PlottypeListBox.ItemsData = {'1', '2', '3', '4'};
            app.PlottypeListBox.Enable = 'off';
            app.PlottypeListBox.Value = '1';
            app.AggregateButtonGroup = uibuttongroup(app.PlotdesignerTab);
            app.AggregateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AggregateButtonGroupSelectionChanged, true);
            app.AggregateButtonGroup.Title = 'Aggregate';
            app.OriginalButton_2 = uiradiobutton(app.AggregateButtonGroup);
            app.OriginalButton_2.Text = 'Original';
            app.OriginalButton_2.Value = true;
            app.MinutesButton_2 = uiradiobutton(app.AggregateButtonGroup);
            app.MinutesButton_2.Text = 'Minutes';
            app.HoursButton_2 = uiradiobutton(app.AggregateButtonGroup);
            app.HoursButton_2.Text = 'Hours';
            app.DaysButton_2 = uiradiobutton(app.AggregateButtonGroup);
            app.DaysButton_2.Text = 'Days';
            app.MonthsButton = uiradiobutton(app.AggregateButtonGroup);
            app.MonthsButton.Text = 'Months';
            app.PlotparametersPanel = uipanel(app.PlotdesignerTab);
            app.PlotparametersPanel.Title = 'Plot parameters';
            app.MinCheckBox = uicheckbox(app.PlotparametersPanel);
            app.MinCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinCheckBoxValueChanged, true);
            app.MinCheckBox.Text = 'Min';
            app.MinCheckBox.Value = true;
            app.MaxCheckBox = uicheckbox(app.PlotparametersPanel);
            app.MaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaxCheckBoxValueChanged, true);
            app.MaxCheckBox.Text = 'Max';
            app.MaxCheckBox.Value = true;
            app.AverageCheckBox = uicheckbox(app.PlotparametersPanel);
            app.AverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageCheckBoxValueChanged, true);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.MedianCheckBox = uicheckbox(app.PlotparametersPanel);
            app.MedianCheckBox.ValueChangedFcn = createCallbackFcn(app, @MedianCheckBoxValueChanged, true);
            app.MedianCheckBox.Text = 'Median';
            app.MedianCheckBox.Value = true;
            app.EditField_Pt1 = uieditfield(app.PlotparametersPanel, 'text');
            app.EditField_Pt1.ValueChangedFcn = createCallbackFcn(app, @EditField_Pt1ValueChanged, true);
            app.EditField_Pt1.Value = '-or';
            app.EditField_Pt2 = uieditfield(app.PlotparametersPanel, 'text');
            app.EditField_Pt2.ValueChangedFcn = createCallbackFcn(app, @EditField_Pt2ValueChanged, true);
            app.EditField_Pt2.Value = '-or';
            app.EditField_Pt3 = uieditfield(app.PlotparametersPanel, 'text');
            app.EditField_Pt3.ValueChangedFcn = createCallbackFcn(app, @EditField_Pt3ValueChanged, true);
            app.EditField_Pt3.Value = '-xb';
            app.EditField_Pt4 = uieditfield(app.PlotparametersPanel, 'text');
            app.EditField_Pt4.ValueChangedFcn = createCallbackFcn(app, @EditField_Pt4ValueChanged, true);
            app.EditField_Pt4.Value = '-xk';
            app.LinestyleLabel = uilabel(app.PlotparametersPanel);
            app.LinestyleLabel.Text = 'Line style';
            app.Panel_2 = uipanel(app.DATAACQUISITIONTab);
            app.SaveFigButton = uibutton(app.Panel_2, 'push');
            app.SaveFigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigButtonPushed, true);
            app.SaveFigButton.Text = 'Save Fig';
            app.SaveJPGButton = uibutton(app.Panel_2, 'push');
            app.SaveJPGButton.ButtonPushedFcn = createCallbackFcn(app, @SaveJPGButtonPushed, true);
            app.SaveJPGButton.Text = 'Save JPG';
            app.SavedataButton = uibutton(app.Panel_2, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.ButtonGrid = uibutton(app.Panel_2, 'push');
            app.ButtonGrid.ButtonPushedFcn = createCallbackFcn(app, @ButtonGridPushed, true);
            app.ButtonGrid.Text = 'Grid ON/OFF';
            app.ButtonGrid_2 = uibutton(app.Panel_2, 'push');
            app.ButtonGrid_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonGrid_2Pushed, true);
            app.ButtonGrid_2.Text = 'AUTO scale';
            app.SHOWButton = uibutton(app.Panel_2, 'push');
            app.SHOWButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWButtonPushed, true);
            app.SHOWButton.Text = 'SHOW !';
            app.ProfileResetButton = uibutton(app.Panel_2, 'push');
            app.ProfileResetButton.ButtonPushedFcn = createCallbackFcn(app, @ProfileResetButtonPushed, true);
            app.ProfileResetButton.Text = 'Reset';
            app.RestoreoriginalButton = uibutton(app.Panel_2, 'push');
            app.RestoreoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreoriginalButtonPushed, true);
            app.RestoreoriginalButton.Text = 'Restore original';
            app.COMPONENTSTab = uitab(app.TabGroup);
            app.COMPONENTSTab.Title = 'COMPONENTS';
            app.PVSPanel = uipanel(app.COMPONENTSTab);
            app.PVSPanel.Title = 'PVS';
            app.DefinetheparametersofPVsystemLabel = uilabel(app.PVSPanel);
            app.DefinetheparametersofPVsystemLabel.Text = 'Define the parameters of PV system';
            app.InstalledpowerkWpEditFieldLabel = uilabel(app.PVSPanel);
            app.InstalledpowerkWpEditFieldLabel.Text = 'Installed power (kWp)';
            app.InstalledpowerkWpEditField = uieditfield(app.PVSPanel, 'numeric');
            app.InstalledpowerkWpEditField.Limits = [0 Inf];
            app.InstalledpowerkWpEditField.ValueChangedFcn = createCallbackFcn(app, @InstalledpowerkWpEditFieldValueChanged, true);
            app.InstalledpowerkWpEditField.Value = 10;
            app.InvertereffEditFieldLabel = uilabel(app.PVSPanel);
            app.InvertereffEditFieldLabel.Text = 'Inverter eff (%)';
            app.InvertereffEditField = uieditfield(app.PVSPanel, 'numeric');
            app.InvertereffEditField.Limits = [0 100];
            app.InvertereffEditField.ValueChangedFcn = createCallbackFcn(app, @InvertereffEditFieldValueChanged, true);
            app.InvertereffEditField.Value = 97;
            app.InverterpowerACmaxEditFieldLabel = uilabel(app.PVSPanel);
            app.InverterpowerACmaxEditFieldLabel.Text = 'Inverter power AC max (%)';
            app.InverterpowerACmaxEditField = uieditfield(app.PVSPanel, 'numeric');
            app.InverterpowerACmaxEditField.Limits = [0 Inf];
            app.InverterpowerACmaxEditField.ValueChangedFcn = createCallbackFcn(app, @InverterpowerACmaxEditFieldValueChanged, true);
            app.InverterpowerACmaxEditField.Value = 10;
            app.NoctEditFieldLabel = uilabel(app.PVSPanel);
            app.NoctEditFieldLabel.Text = 'Noct';
            app.NoctEditField = uieditfield(app.PVSPanel, 'numeric');
            app.NoctEditField.Limits = [0 100];
            app.NoctEditField.ValueChangedFcn = createCallbackFcn(app, @NoctEditFieldValueChanged, true);
            app.NoctEditField.Value = 48;
            app.NambEditFieldLabel = uilabel(app.PVSPanel);
            app.NambEditFieldLabel.Text = 'Namb';
            app.NambEditField = uieditfield(app.PVSPanel, 'numeric');
            app.NambEditField.Limits = [0 100];
            app.NambEditField.ValueChangedFcn = createCallbackFcn(app, @NambEditFieldValueChanged, true);
            app.NambEditField.Value = 15;
            app.ModulepowertempcoeffEditFieldLabel = uilabel(app.PVSPanel);
            app.ModulepowertempcoeffEditFieldLabel.Text = 'Module power temp coeff (%)';
            app.ModulepowertempcoeffEditField = uieditfield(app.PVSPanel, 'numeric');
            app.ModulepowertempcoeffEditField.Limits = [-1 0];
            app.ModulepowertempcoeffEditField.ValueChangedFcn = createCallbackFcn(app, @ModulepowertempcoeffEditFieldValueChanged, true);
            app.ModulepowertempcoeffEditField.Value = -0.4;
            app.ESSPanel = uipanel(app.COMPONENTSTab);
            app.ESSPanel.Title = 'ESS';
            app.DefinetheparametersofESSbatterystoragesystemLabel = uilabel(app.ESSPanel);
            app.DefinetheparametersofESSbatterystoragesystemLabel.Text = 'Define the parameters of ESS (battery storage system)';
            app.CappacittykWhEditFieldLabel = uilabel(app.ESSPanel);
            app.CappacittykWhEditFieldLabel.Text = 'Cappacitty (kWh)';
            app.CappacittykWhEditField = uieditfield(app.ESSPanel, 'numeric');
            app.CappacittykWhEditField.Limits = [0 Inf];
            app.CappacittykWhEditField.ValueChangedFcn = createCallbackFcn(app, @CappacittykWhEditFieldValueChanged, true);
            app.CappacittykWhEditField.Value = 50;
            app.InitialSOCEditFieldLabel = uilabel(app.ESSPanel);
            app.InitialSOCEditFieldLabel.Text = 'Initial SOC (%)';
            app.InitialSOCEditField = uieditfield(app.ESSPanel, 'numeric');
            app.InitialSOCEditField.Limits = [0 100];
            app.InitialSOCEditField.Value = 50;
            app.CyclingmaxEditFieldLabel = uilabel(app.ESSPanel);
            app.CyclingmaxEditFieldLabel.Text = 'Cycling max (%)';
            app.CyclingmaxEditField = uieditfield(app.ESSPanel, 'numeric');
            app.CyclingmaxEditField.Limits = [0 100];
            app.CyclingmaxEditField.Value = 80;
            app.ChargeefficiencyEditFieldLabel = uilabel(app.ESSPanel);
            app.ChargeefficiencyEditFieldLabel.Enable = 'off';
            app.ChargeefficiencyEditFieldLabel.Text = 'Charge efficiency (%)';
            app.ChargeefficiencyEditField = uieditfield(app.ESSPanel, 'numeric');
            app.ChargeefficiencyEditField.Limits = [0 100];
            app.ChargeefficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @ChargeefficiencyEditFieldValueChanged, true);
            app.ChargeefficiencyEditField.Enable = 'off';
            app.ChargeefficiencyEditField.Value = 100;
            app.DischargeefficiencyEditFieldLabel = uilabel(app.ESSPanel);
            app.DischargeefficiencyEditFieldLabel.Enable = 'off';
            app.DischargeefficiencyEditFieldLabel.Text = 'Discharge efficiency (%)';
            app.DischargeefficiencyEditField = uieditfield(app.ESSPanel, 'numeric');
            app.DischargeefficiencyEditField.Limits = [0 100];
            app.DischargeefficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @DischargeefficiencyEditFieldValueChanged, true);
            app.DischargeefficiencyEditField.Enable = 'off';
            app.DischargeefficiencyEditField.Value = 100;
            app.CyclingminLabel = uilabel(app.ESSPanel);
            app.CyclingminLabel.Text = 'Cycling min (%)';
            app.CyclingminEditField = uieditfield(app.ESSPanel, 'numeric');
            app.PowermaxkWEditFieldLabel = uilabel(app.ESSPanel);
            app.PowermaxkWEditFieldLabel.Text = 'Power max (kW)';
            app.ESSPowermaxkWEditField = uieditfield(app.ESSPanel, 'numeric');
            app.ESSPowermaxkWEditField.Value = 10;
            app.GRIDPanel = uipanel(app.COMPONENTSTab);
            app.GRIDPanel.Title = 'GRID';
            app.DefinethegridparametersLabel = uilabel(app.GRIDPanel);
            app.DefinethegridparametersLabel.Text = 'Define the grid parameters';
            app.PowerlimitoutkWEditFieldLabel = uilabel(app.GRIDPanel);
            app.PowerlimitoutkWEditFieldLabel.Text = 'Power limit out (kW)';
            app.PowerlimitoutkWEditField = uieditfield(app.GRIDPanel, 'numeric');
            app.PowerlimitoutkWEditField.Limits = [0 Inf];
            app.PowerlimitoutkWEditField.Value = 10;
            app.PowerlimitininjectionkWEditFieldLabel = uilabel(app.GRIDPanel);
            app.PowerlimitininjectionkWEditFieldLabel.Enable = 'off';
            app.PowerlimitininjectionkWEditFieldLabel.Text = 'Power limit in = injection (kW)';
            app.PowerlimitininjectionkWEditField = uieditfield(app.GRIDPanel, 'numeric');
            app.PowerlimitininjectionkWEditField.Limits = [0 100];
            app.PowerlimitininjectionkWEditField.Enable = 'off';
            app.SIMULATIONTab = uitab(app.TabGroup);
            app.SIMULATIONTab.Title = 'SIMULATION';
            app.InputdatasetsPanel = uipanel(app.SIMULATIONTab);
            app.InputdatasetsPanel.Title = 'Input data sets';
            app.CommontimerangeCheckBox = uicheckbox(app.InputdatasetsPanel);
            app.CommontimerangeCheckBox.Text = 'Common time range';
            app.StartdatetimeEditField_2Label = uilabel(app.InputdatasetsPanel);
            app.StartdatetimeEditField_2Label.Text = 'Start datetime';
            app.StartdatetimeEditField_2 = uieditfield(app.InputdatasetsPanel, 'text');
            app.StartdatetimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartdatetimeEditField_2ValueChanged, true);
            app.TimestepsEditFieldLabel = uilabel(app.InputdatasetsPanel);
            app.TimestepsEditFieldLabel.Text = 'Timesteps';
            app.TimestepsEditField = uieditfield(app.InputdatasetsPanel, 'numeric');
            app.TimestepsEditField.Limits = [2 200];
            app.TimestepsEditField.RoundFractionalValues = 'on';
            app.TimestepsEditField.Value = 200;
            app.DatePicker = uidatepicker(app.InputdatasetsPanel);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Value = datetime([2019 5 1]);
            app.UIAxesSynch = uiaxes(app.InputdatasetsPanel);
            title(app.UIAxesSynch, 'Data time synchronizing')
            ylabel(app.UIAxesSynch, '-')
            app.UIAxesPrice = uiaxes(app.InputdatasetsPanel);
            title(app.UIAxesPrice, 'Grid price')
            ylabel(app.UIAxesPrice, 'kW')
            app.UIAxesLoad = uiaxes(app.InputdatasetsPanel);
            title(app.UIAxesLoad, 'Load')
            ylabel(app.UIAxesLoad, 'kW')
            app.UIAxesPVS = uiaxes(app.InputdatasetsPanel);
            title(app.UIAxesPVS, 'PVS AC output')
            ylabel(app.UIAxesPVS, 'kW')
            app.DatareadyLampLabel = uilabel(app.InputdatasetsPanel);
            app.DatareadyLampLabel.Text = 'Data ready';
            app.DatareadyLamp = uilamp(app.InputdatasetsPanel);
            app.SHOWPREPAREDATAButton = uibutton(app.InputdatasetsPanel, 'push');
            app.SHOWPREPAREDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWPREPAREDATAButtonPushed, true);
            app.SHOWPREPAREDATAButton.Text = 'SHOW & PREPARE DATA';
            app.SimulationoutputscontrolPanel = uipanel(app.SIMULATIONTab);
            app.SimulationoutputscontrolPanel.Title = 'Simulation outputs & control';
            app.UIAxesResult2 = uiaxes(app.SimulationoutputscontrolPanel);
            title(app.UIAxesResult2, {'SOC'; ' Grid Price'})
            xlabel(app.UIAxesResult2, 'time')
            ylabel(app.UIAxesResult2, '-')
            app.SaveFigButton_2 = uibutton(app.SimulationoutputscontrolPanel, 'push');
            app.SaveFigButton_2.Enable = 'off';
            app.SaveFigButton_2.Text = 'Save Fig';
            app.SaveJPGButton_2 = uibutton(app.SimulationoutputscontrolPanel, 'push');
            app.SaveJPGButton_2.Enable = 'off';
            app.SaveJPGButton_2.Text = 'Save JPG';
            app.SavedataButton_2 = uibutton(app.SimulationoutputscontrolPanel, 'push');
            app.SavedataButton_2.Enable = 'off';
            app.SavedataButton_2.Text = 'Save data';
            app.UIAxesResult1 = uiaxes(app.SimulationoutputscontrolPanel);
            title(app.UIAxesResult1, 'Power (PV GRID ESS)')
            xlabel(app.UIAxesResult1, 'time')
            ylabel(app.UIAxesResult1, '-')
            app.ControlstrategyButtonGroup = uibuttongroup(app.SimulationoutputscontrolPanel);
            app.ControlstrategyButtonGroup.Title = 'Control strategy';
            app.LoadfollowingButton = uiradiobutton(app.ControlstrategyButtonGroup);
            app.LoadfollowingButton.Enable = 'off';
            app.LoadfollowingButton.Text = 'Load following';
            app.PredictivemodelButton = uiradiobutton(app.ControlstrategyButtonGroup);
            app.PredictivemodelButton.Enable = 'off';
            app.PredictivemodelButton.Text = 'Predictive model';
            app.PredictivemodelButton.Value = true;
            app.RUNSIMULATIONButton = uibutton(app.SimulationoutputscontrolPanel, 'push');
            app.RUNSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSIMULATIONButtonPushed, true);
            app.RUNSIMULATIONButton.Enable = 'off';
            app.RUNSIMULATIONButton.Text = 'RUN SIMULATION';
            app.StatTextArea = uitextarea(app.SimulationoutputscontrolPanel);
            app.StatusLabel = uilabel(app.SimulationoutputscontrolPanel);
            app.StatusLabel.Text = 'Status';
            app.AUTOscaleButton = uibutton(app.SimulationoutputscontrolPanel, 'push');
            app.AUTOscaleButton.ButtonPushedFcn = createCallbackFcn(app, @AUTOscaleButtonPushed, true);
            app.AUTOscaleButton.Text = 'AUTO scale';
            app.Panel_3 = uipanel(app.SimulationoutputscontrolPanel);
            app.ShowPVCheckBox = uicheckbox(app.Panel_3);
            app.ShowPVCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPVCheckBoxValueChanged, true);
            app.ShowPVCheckBox.Text = 'PV';
            app.ShowPVCheckBox.Value = true;
            app.ShowGridCheckBox = uicheckbox(app.Panel_3);
            app.ShowGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGridCheckBoxValueChanged, true);
            app.ShowGridCheckBox.Text = 'Grid';
            app.ShowGridCheckBox.Value = true;
            app.ShowESSCheckBox = uicheckbox(app.Panel_3);
            app.ShowESSCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowESSCheckBoxValueChanged, true);
            app.ShowESSCheckBox.Text = 'ESS';
            app.ShowESSCheckBox.Value = true;
            app.ShowLoadCheckBox = uicheckbox(app.Panel_3);
            app.ShowLoadCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLoadCheckBoxValueChanged, true);
            app.ShowLoadCheckBox.Text = 'Load';
            app.ShowLoadCheckBox.Value = true;
            app.ClearlogButton = uibutton(app.SimulationoutputscontrolPanel, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ESSControl

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end